classdef PRoyectofinal_OA_LJ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        PuertoComEditFieldLabel         matlab.ui.control.Label
        PuertoComEditField              matlab.ui.control.EditField
        ConectarButton                  matlab.ui.control.Button
        TiempodesenialmuestradasEditFieldLabel  matlab.ui.control.Label
        TiempodesenialmuestradasEditField  matlab.ui.control.NumericEditField
        CargarButton                    matlab.ui.control.Button
        AplicarfiltrosasenialcargadaButton  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        FrecunciadecorteHzlowEditFieldLabel  matlab.ui.control.Label
        FrecunciadecorteHzlowEditField  matlab.ui.control.NumericEditField
        VisualizarfiltroButton          matlab.ui.control.Button
        FrecunciadecorteHzhighEditFieldLabel  matlab.ui.control.Label
        FrecunciadecorteHzhighEditField  matlab.ui.control.NumericEditField
        FiltrosDropDownLabel            matlab.ui.control.Label
        FiltrosDropDown                 matlab.ui.control.DropDown
        FormadelfiltroSwitchLabel       matlab.ui.control.Label
        FormadelfiltroSwitch            matlab.ui.control.RockerSwitch
        OrdendefiltroEditFieldLabel     matlab.ui.control.Label
        OrdendefiltroEditField          matlab.ui.control.NumericEditField
        EjeYButtonGroup                 matlab.ui.container.ButtonGroup
        dbButton                        matlab.ui.control.RadioButton
        NodbButton                      matlab.ui.control.RadioButton
        EnviarFiltroButton              matlab.ui.control.Button
        Efectos___________Label         matlab.ui.control.Label
        AplicarFiltroButton             matlab.ui.control.Button
        TipodeFiltroDropDownLabel       matlab.ui.control.Label
        TipodeFiltroDropDown            matlab.ui.control.DropDown
        ConexionaTivaCheckBox           matlab.ui.control.CheckBox
        Ordenmaximodeenvioalativaes10Label  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        GuardarButton                   matlab.ui.control.Button
        Propiedadesparaguardar___________Label  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Puerto_serial
        tiempo_de_senial
        senial
%         tab 2
        formadefiltros
        filtros
        tiposdefiltros
        frecuanciadecorte
        orden
        db
        Wn
        Wn2
        fc
        senial_filtrada
        h
        w
        H
        W
               
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            global fs;
            tiva = serialport(app.PuertoComEditField.Value, 1000000); %el puerto puede variar entre computadores
            %frecuecia de muestreo en Hz.
            fs = 44200;
            % vectores de datos 
            N = fs*app.TiempodesenialmuestradasEditField.Value;
            app.senial = zeros(1,N);
            n = 1:N;
            flush(tiva);
            for i=n
            app.senial(i) = str2double(readline(tiva));
            end 
            clear tiva
            plot(app.UIAxes,n,app.senial)
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            global file
            global senial 
            [file,path] = uigetfile("*.mat*");%Ubica un directorio y logra usar cualquier tipo de archivo
            addpath(path);
           % data=strcat(file,'.mat');
           load(file,'senial');
            app.senial = senial;
            n=length(app.senial);
            
            plot(app.UIAxes,1:n,app.senial)
        end

        % Value changed function: FiltrosDropDown
        function FiltrosDropDownValueChanged(app, event)
            if(strcmp(app.FiltrosDropDown.Value,"high"))
                app.FrecunciadecorteHzlowEditField.Enable=false;
            else 
                app.FrecunciadecorteHzlowEditField.Enable=true;
            end
            if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                app.FrecunciadecorteHzhighEditField.Enable=true;
                app.FrecunciadecorteHzlowEditField.Enable=true;
            end
            if(strcmp(app.FiltrosDropDown.Value,"low"))
                app.FrecunciadecorteHzhighEditField.Enable=false;
            else 
                app.FrecunciadecorteHzhighEditField.Enable=true;
            end
        end

        % Button pushed function: VisualizarfiltroButton
        function boton_del_aplicacion(app, event)
            global a;
            global b;
            global c;
            global fs;
            fs = 1000;
%___________________________       selector en fii      ____________________________________________
            if(strcmp(app.FormadelfiltroSwitch.Value,"IIR"))
%**********************************      butter     ************************************************   
               if(strcmp(app.TipodeFiltroDropDown.Value,"Butterwoth"))
%___________________________hacer fir en modo high__________________________________________________
                
                    if(strcmp(app.FiltrosDropDown.Value,"high"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        [a,b]=butter((app.OrdendefiltroEditField.Value),(app.Wn),("high"));
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo Bandpass______________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=butter((app.OrdendefiltroEditField.Value),[app.Wn2 app.Wn],"bandpass");
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo low___________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"low"))
                        app.Wn=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=butter((app.OrdendefiltroEditField.Value),(app.Wn),("low"));
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                        end
                    end
               end 
%*******************************      chebychefe    ************************************************ 
               if(strcmp(app.TipodeFiltroDropDown.Value,"Chebyshev"))
%___________________________hacer fir en modo high__________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"high"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        [a,b]=cheby1(app.OrdendefiltroEditField.Value,0.1,(app.Wn),("high"));      
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo Bandpass______________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;                       
                        [a,b]=cheby1((app.OrdendefiltroEditField.Value),0.1,[app.Wn2 app.Wn],"bandpass");
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo low___________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"low"))
                        app.Wn=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=cheby1((app.OrdendefiltroEditField.Value),(app.Wn),("low"));
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                        end
                    end
               end
               
%*************************************     ellip      **********************************************               
               if(strcmp(app.TipodeFiltroDropDown.Value,"Elliptico"))
%___________________________hacer fir en modo high__________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"high"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        
                        
                        [a,b]=ellip(app.OrdendefiltroEditField.Value,(1),(40),(app.Wn),("high"));
                        
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo Bandpass______________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=ellip(app.OrdendefiltroEditField.Value,(0.1),(40),[app.Wn2 app.Wn],"bandpass");
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo low___________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"low"))
                        app.Wn=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=ellip(app.OrdendefiltroEditField.Value,(1),(40),(app.Wn),("low"));
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                        end
                    end
               end


            end % end de if iir


%___________________________       selector en fir      ____________________________________________
            if(strcmp(app.FormadelfiltroSwitch.Value,"FIR"))
%___________________________hacer fir en modo high__________________________________________________
                if(strcmp(app.FiltrosDropDown.Value,"high"))
                    app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                    c=fir1((app.OrdendefiltroEditField.Value),(app.Wn),("high"));
                    [app.h,app.w]=freqz(c,1);
                    if (app.NodbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                    end
                    if (app.dbButton.Value)
                        plot(app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                    end
                end
%___________________________hacer fir en modo bandpas_______________________________________________ 
                if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                    app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                    app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                    c=fir1((app.OrdendefiltroEditField.Value),[app.Wn2 app.Wn],"bandpass");
                    [app.h,app.w]=freqz(c,1,512,"whole");
                    if (app.NodbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                    end
                    if (app.dbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                    end
                end
%___________________________hacer fir en modo Low___________________________________________________
                if(strcmp(app.FiltrosDropDown.Value,"low"))
                    app.Wn=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                    
                    c=fir1((app.OrdendefiltroEditField.Value),(app.Wn),("low"));
                    
                    [app.h,app.w]=freqz(c,1);
                    if (app.NodbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                    end
                    if (app.dbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                    end
                end
            end
        end

        % Button pushed function: EnviarFiltroButton
        function EnviarFiltroButtonPushed(app, event)

            if(strcmp(app.FormadelfiltroSwitch.Value,"IIR"))
                a1=vpa(a,10);
                b1=vpa(b,10);
                
                n1=length(a);
                n2=length(b);
                
                N1=sprintf('%d,',n1);
                N2=sprintf('%d,',n2);
                A=sprintf('%f,',a1);
                B=sprintf('%f,',b1);
                B=B(1:lenghth(B)-1);
                datassent=strcat(N1,N2,A,B);
                tiva = serialport(app.PuertoComEditField.Value, 1000000); %el puerto puede variar entre computadores
                writeline(tiva,datassent);
                clear tiva
            end
            if(strcmp(app.FormadelfiltroSwitch.Value,"FIR"))
                c1=vpa(c,10);
                n3=length(c);
                C=sprintf('%f',c1);
                N3=sprintf('%d,',n3);
                datassent=strcat(N3,C);
                tiva = serialport(app.PuertoComEditField.Value, 1000000); %el puerto puede variar entre computadores
                writeline(tiva,datassent)
                clear tiva  
            end 
            
        end

        % Value changed function: ConexionaTivaCheckBox
        function ConexionaTivaCheckBoxValueChanged(app, event)
            value = app.ConexionaTivaCheckBox.Value;
            if(app.ConexionaTivaCheckBox.Value)
                app.Ordenmaximodeenvioalativaes10Label.Visible=true;
                app.EnviarFiltroButton.Enable = true;
            else 
                app.Ordenmaximodeenvioalativaes10Label.Visible=false;
                app.EnviarFiltroButton.Enable = false;
            end
        end

        % Value changed function: OrdendefiltroEditField
        function OrdendefiltroEditFieldValueChanged(app, event)
            value = app.OrdendefiltroEditField.Value; 
            if(value > 10 && app.ConexionaTivaCheckBox.Value)
                app.EnviarFiltroButton.Enable = false;   
            else 
                app.EnviarFiltroButton.Enable = true;
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            [newname,newpath]=uiputfile('.mat','Save Data',app.senial);   
            newfile=fullfile(newpath, newname);
            variables=strcat(newfile,app.VariablesEditField.Value,'.mat');
            filenamevariables=fullfile(newpath,variables);
            save(filenamevariables,'-mat');
            
%             [newname,newpath]=uiputfile('.mat','Save Data',app.VariablesEditField.Value);
%             newfile=fullfile(newpath, newname);
%             variables=strcat(newname,newpath,'.mat');
%             save(variables,'-mat');
        end

        % Value changed function: FormadelfiltroSwitch
        function FormadelfiltroSwitchValueChanged(app, event)
            if(strcmp(app.FormadelfiltroSwitch.Value,"FIR"))
                app.TipodeFiltroDropDown.Enable = false;   
            else 
                app.TipodeFiltroDropDown.Enable = true;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.PuertoComEditFieldLabel = uilabel(app.Tab);
            app.PuertoComEditFieldLabel.Text = 'Puerto Com';
            app.PuertoComEditField = uieditfield(app.Tab, 'text');
            app.ConectarButton = uibutton(app.Tab, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.TiempodesenialmuestradasEditFieldLabel = uilabel(app.Tab);
            app.TiempodesenialmuestradasEditFieldLabel.Text = 'Tiempo de senial muestrada (s) ';
            app.TiempodesenialmuestradasEditField = uieditfield(app.Tab, 'numeric');
            app.CargarButton = uibutton(app.Tab, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar ';
            app.AplicarfiltrosasenialcargadaButton = uibutton(app.Tab, 'push');
            app.AplicarfiltrosasenialcargadaButton.Text = 'Aplicar filtros a senial cargada ';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.FrecunciadecorteHzlowEditFieldLabel = uilabel(app.Tab2);
            app.FrecunciadecorteHzlowEditFieldLabel.Text = 'Frecuncia de corte (Hz) low ';
            app.FrecunciadecorteHzlowEditField = uieditfield(app.Tab2, 'numeric');
            app.VisualizarfiltroButton = uibutton(app.Tab2, 'push');
            app.VisualizarfiltroButton.ButtonPushedFcn = createCallbackFcn(app, @boton_del_aplicacion, true);
            app.VisualizarfiltroButton.Text = 'Visualizar filtro';
            app.FrecunciadecorteHzhighEditFieldLabel = uilabel(app.Tab2);
            app.FrecunciadecorteHzhighEditFieldLabel.Text = 'Frecuncia de corte (Hz) high ';
            app.FrecunciadecorteHzhighEditField = uieditfield(app.Tab2, 'numeric');
            app.FiltrosDropDownLabel = uilabel(app.Tab2);
            app.FiltrosDropDownLabel.Text = 'Filtros ';
            app.FiltrosDropDown = uidropdown(app.Tab2);
            app.FiltrosDropDown.Items = {'...', 'low', 'bandpass', 'high'};
            app.FiltrosDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltrosDropDownValueChanged, true);
            app.FiltrosDropDown.Value = '...';
            app.FormadelfiltroSwitchLabel = uilabel(app.Tab2);
            app.FormadelfiltroSwitchLabel.Text = 'Forma del filtro ';
            app.FormadelfiltroSwitch = uiswitch(app.Tab2, 'rocker');
            app.FormadelfiltroSwitch.Items = {'IIR', 'FIR'};
            app.FormadelfiltroSwitch.ValueChangedFcn = createCallbackFcn(app, @FormadelfiltroSwitchValueChanged, true);
            app.FormadelfiltroSwitch.Value = 'IIR';
            app.OrdendefiltroEditFieldLabel = uilabel(app.Tab2);
            app.OrdendefiltroEditFieldLabel.Text = 'Orden de filtro';
            app.OrdendefiltroEditField = uieditfield(app.Tab2, 'numeric');
            app.OrdendefiltroEditField.ValueChangedFcn = createCallbackFcn(app, @OrdendefiltroEditFieldValueChanged, true);
            app.EjeYButtonGroup = uibuttongroup(app.Tab2);
            app.EjeYButtonGroup.Title = 'Eje Y';
            app.dbButton = uiradiobutton(app.EjeYButtonGroup);
            app.dbButton.Text = 'db ';
            app.dbButton.Value = true;
            app.NodbButton = uiradiobutton(app.EjeYButtonGroup);
            app.NodbButton.Text = 'No db ';
            app.EnviarFiltroButton = uibutton(app.Tab2, 'push');
            app.EnviarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarFiltroButtonPushed, true);
            app.EnviarFiltroButton.Text = 'Enviar Filtro ';
            app.Efectos___________Label = uilabel(app.Tab2);
            app.Efectos___________Label.Text = '     __________ Efectos ___________';
            app.AplicarFiltroButton = uibutton(app.Tab2, 'push');
            app.AplicarFiltroButton.Text = 'Aplicar Filtro ';
            app.TipodeFiltroDropDownLabel = uilabel(app.Tab2);
            app.TipodeFiltroDropDownLabel.Text = 'Tipo de Filtro';
            app.TipodeFiltroDropDown = uidropdown(app.Tab2);
            app.TipodeFiltroDropDown.Items = {'...', 'Butterwoth', 'Chebyshev', 'Elliptico'};
            app.TipodeFiltroDropDown.Value = '...';
            app.ConexionaTivaCheckBox = uicheckbox(app.Tab2);
            app.ConexionaTivaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConexionaTivaCheckBoxValueChanged, true);
            app.ConexionaTivaCheckBox.Text = 'Conexion a Tiva ?';
            app.Ordenmaximodeenvioalativaes10Label = uilabel(app.Tab2);
            app.Ordenmaximodeenvioalativaes10Label.Text = 'Orden maximo de envio a la tiva es 10 ';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.GuardarButton = uibutton(app.Tab3, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Propiedadesparaguardar___________Label = uilabel(app.Tab3);
            app.Propiedadesparaguardar___________Label.Text = '     __________ Propiedades para guardar  ___________';
            app.UIAxes_2 = uiaxes(app.Tab3);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRoyectofinal_OA_LJ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end